classdef mixerCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMONATIMSMixerCalibrationv03UIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MixerCalibrationTab           matlab.ui.container.Tab
        RunTxCalibrationButton        matlab.ui.control.StateButton
        AnalogRxFilterTuningButton    matlab.ui.control.StateButton
        RunRxCalibrationButton        matlab.ui.control.StateButton
        Status                        matlab.ui.control.Label
        DSOSettingsTab                matlab.ui.container.Tab
        VISADriverTypeEditFieldLabel  matlab.ui.control.Label
        VISADriverTypeEditField       matlab.ui.control.EditField
        VISADeviceAddressEditFieldLabel  matlab.ui.control.Label
        VISADeviceAddressEditField    matlab.ui.control.EditField
        EnableSimulatorModeCheckBox   matlab.ui.control.CheckBox
        ReadSingleSidebandButton      matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            close all;
            clc;
            try
                addpath('functions\');
            catch
                warning('Functions folder not found');
            end
        end

        % Callback function
        function AmpSliderValueChanged(app, event)
            
        end

        % Value changed function: RunTxCalibrationButton
        function RunTxCalibrationButtonValueChanged(app, event)
            
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
            
            global VISAtype;
            global VISAaddr;
            VISAaddr = app.VISADeviceAddressEditField.Value;
            VISAtype = app.VISADriverTypeEditField.Value;
            
            
            app.Status.Text = 'Running Tx Calibration';
            app.Status.FontColor = 'Black';
            try
                readTxCal
                app.Status.Text = 'Tx Calibration Successful';
                app.Status.FontColor = [0.47 0.67 0.19];
            catch ME
                warning('Error Running readTxCal');
                warning(ME.message);
                app.Status.Text = 'An Error Occured During Tx Calibration';
                app.Status.FontColor = [0.64 0.08 0.18];
            end
            app.RunTxCalibrationButton.Value = 0;
            % Disable/Enable visablity to bring app window back to the foreground
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 0;
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 1;
        end

        % Value changed function: RunRxCalibrationButton
        function RunRxCalibrationButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
            
            global VISAtype;
            global VISAaddr;
            VISAaddr = app.VISADeviceAddressEditField.Value;
            VISAtype = app.VISADriverTypeEditField.Value;
            
            
            app.Status.Text = 'Running Rx Calibration';
            app.Status.FontColor = 'Black';
            
            try
                readRxCal
                app.Status.Text = 'Rx Calibration Successful';
                app.Status.FontColor = [0.47 0.67 0.19];
            catch ME
                warning('Error Running readRxCal');
                warning(ME.message);
                app.Status.Text = 'An Error Occured During Rx Calibration';
                app.Status.FontColor = [0.64 0.08 0.18];
            end
            
            app.RunRxCalibrationButton.Value = 0;
            % Disable/Enable visablity to bring app window back to the foreground
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 0;
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 1;
        end

        % Callback function
        function BuildRxCalFilesButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
            buildRxCal
            app.BuildRxCalFilesButton.Value = 0;
            app.RunRxCalibrationButton.Enable = 1;
            
        end

        % Value changed function: AnalogRxFilterTuningButton
        function AnalogRxFilterTuningButtonValueChanged(app, event)
            
            
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
            if ~SIM_MODE
                
                VISAaddr = app.VISADeviceAddressEditField.Value;
                VISAtype = app.VISADriverTypeEditField.Value;
                
                app.Status.Text = 'Setting Up Filter Calibration';
                app.Status.FontColor = 'Black';
                try
                    
                    % Send Pulse to AWG
                    buildFiltCal();
                    % Set Rigol to Filter Viewing mode
                    setRigol(4,[],[],VISAtype,VISAaddr);
                    
                    app.Status.Text = 'DSO Set Successful';
                    app.Status.FontColor = [0.47 0.67 0.19];
                    
                    
                    % PLot Examples
                    if isfile('Data Files\filter_tuned.mat') && isfile('Data Files\filter_untuned.mat')
                        disp('try plots');
                        a = load('Data Files\filter_untuned.mat');
                        figure;
                        plot(a.tq,a.F1rx,a.tq,a.F2rx);
                        grid on; grid minor;
                        legend('Filter One','Filter Two');
                        title('Example Untuned Filter Response');
                        
                        a = load('Data Files\filter_tuned.mat');
                        figure
                        plot(a.tq,a.F1rx,a.tq,a.F2rx);
                        grid on; grid minor;
                        legend('Filter One','Filter Two');
                        title('Example Tuned Filter Response');
                        
                    end


                catch ME
                    warning('Error Running setRigol');
                    warning(ME.message);
                    app.Status.Text = 'An Error Occured';
                    app.Status.FontColor = [0.64 0.08 0.18];
                end
            else
                
                % PLot Examples
                if isfile('Data Files\filter_tuned.mat') && isfile('Data Files\filter_untuned.mat')
                    disp('try plots');
                    a = load('Data Files\filter_untuned.mat');
                    figure;
                    plot(a.tq,a.F1rx,a.tq,a.F2rx);
                    grid on; grid minor;
                    legend('Filter One','Filter Two');
                    title('Example Untuned Filter Response');
                    
                    a = load('Data Files\filter_tuned.mat');
                    figure
                    plot(a.tq,a.F1rx,a.tq,a.F2rx);
                    grid on; grid minor;
                    legend('Filter One','Filter Two');
                    title('Example Tuned Filter Response');
                    
                end
            end
            
            app.AnalogRxFilterTuningButton.Value = 0;
            % Disable/Enable visablity to bring app window back to the foreground
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 0;
            app.EMONATIMSMixerCalibrationv03UIFigure.Visible = 1;
            
        end

        % Value changed function: ReadSingleSidebandButton
        function ReadSingleSidebandButtonValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
            readRx_sb_calcd
            app.ReadSingleSidebandButton.Value = 0;
            
        end

        % Value changed function: EnableSimulatorModeCheckBox
        function EnableSimulatorModeCheckBoxValueChanged(app, event)
            global SIM_MODE
            SIM_MODE = app.EnableSimulatorModeCheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMONATIMSMixerCalibrationv03UIFigure = uifigure;
            app.EMONATIMSMixerCalibrationv03UIFigure.Name = 'EMONA TIMS Mixer Calibration v0.3';
            app.TabGroup = uitabgroup(app.EMONATIMSMixerCalibrationv03UIFigure);
            app.MixerCalibrationTab = uitab(app.TabGroup);
            app.MixerCalibrationTab.Title = 'Mixer Calibration';
            app.RunTxCalibrationButton = uibutton(app.MixerCalibrationTab, 'state');
            app.RunTxCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunTxCalibrationButtonValueChanged, true);
            app.RunTxCalibrationButton.Text = 'Run Tx Calibration';
            app.AnalogRxFilterTuningButton = uibutton(app.MixerCalibrationTab, 'state');
            app.AnalogRxFilterTuningButton.ValueChangedFcn = createCallbackFcn(app, @AnalogRxFilterTuningButtonValueChanged, true);
            app.AnalogRxFilterTuningButton.Text = 'Analog Rx Filter Tuning';
            app.RunRxCalibrationButton = uibutton(app.MixerCalibrationTab, 'state');
            app.RunRxCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunRxCalibrationButtonValueChanged, true);
            app.RunRxCalibrationButton.Text = 'Run Rx Calibration';
            app.Status = uilabel(app.MixerCalibrationTab);
            app.Status.Text = '';
            app.DSOSettingsTab = uitab(app.TabGroup);
            app.DSOSettingsTab.Title = 'DSO Settings';
            app.VISADriverTypeEditFieldLabel = uilabel(app.DSOSettingsTab);
            app.VISADriverTypeEditFieldLabel.Text = 'VISA Driver Type';
            app.VISADriverTypeEditField = uieditfield(app.DSOSettingsTab, 'text');
            app.VISADriverTypeEditField.Value = 'KEYSIGHT';
            app.VISADeviceAddressEditFieldLabel = uilabel(app.DSOSettingsTab);
            app.VISADeviceAddressEditFieldLabel.Text = 'VISA Device Address';
            app.VISADeviceAddressEditField = uieditfield(app.DSOSettingsTab, 'text');
            app.VISADeviceAddressEditField.Value = 'USB0::0x1AB1::0x04B1::DS4A194800709::0::INSTR';
            app.EnableSimulatorModeCheckBox = uicheckbox(app.DSOSettingsTab);
            app.EnableSimulatorModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSimulatorModeCheckBoxValueChanged, true);
            app.EnableSimulatorModeCheckBox.Text = 'Enable Simulator Mode';
            app.ReadSingleSidebandButton = uibutton(app.EMONATIMSMixerCalibrationv03UIFigure, 'state');
            app.ReadSingleSidebandButton.ValueChangedFcn = createCallbackFcn(app, @ReadSingleSidebandButtonValueChanged, true);
            app.ReadSingleSidebandButton.Enable = 'off';
            app.ReadSingleSidebandButton.Text = 'Read Single Sideband';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mixerCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMONATIMSMixerCalibrationv03UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMONATIMSMixerCalibrationv03UIFigure)
        end
    end
end